classdef FourierSeriesVisualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FourierSeriesVizualizerUIFigure  matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        FourierSeriesVizualizerLabel  matlab.ui.control.Label
        SignalTypeListBoxLabel        matlab.ui.control.Label
        SignalTypeListBox             matlab.ui.control.ListBox
        TimeKnobLabel                 matlab.ui.control.Label
        TimeKnob                      matlab.ui.control.Knob
        NumberofwavesKnobLabel        matlab.ui.control.Label
        NumberofwavesKnob             matlab.ui.control.Knob
        FrequencyKnobLabel            matlab.ui.control.Label
        FrequencyKnob                 matlab.ui.control.Knob
    end


    properties (Access = private)
        N; % Number of waves
        t; % time
        f; % frequency
        mode; % mode
        ref; % reference signal
        y; % signal
    end

    methods (Access = private)
        % Fourier Series of Square Wave
        function sig = square_wave_fourier(app)
            sig = zeros(size(app.t));
            for id = 1:app.N
                if(mod(id, 2)==1)
                    sig = sig + (1/id)*sin(id*2*pi*app.f*app.t);
                end
            end
            sig = sig*(4/pi);
        end
        
        % Fourier Series of Triangle Wave
        function sig = triangle_wave_fourier(app)
            sig = zeros(size(app.t));
            for id = 1:app.N
                if(mod(id, 2)==1)
                    sig = sig + (1/id.^2)*cos(id*2*pi*app.f*app.t);
                end
            end
            sig = sig*(8/pi^2);
        end
        
        % Fourier Series of Saw-Tooth Wave
        function sig = saw_tooth_wave_fourier(app)
            sig = zeros(size(app.t));
            for id = 1:app.N
                sig = sig + (((-1).^id)/id)*sin(id*2*pi*app.f*app.t);
            end
            sig = -sig*(2/pi);
        end
        
        % Square Wave
        function sig = square_wave_ref(app)
            sig = zeros(size(app.t));
            th = 1/(2*app.f);
            for id = 1:length(app.t)
                if(app.t(id) < th)
                    sig(id) = 1;
                elseif(app.t(id) < (th+(1/(2*app.f))))
                    sig(id) = -1;
                else
                    th = th + 1/app.f;
                    sig(id) = 1;
                end
            end
        end
        
        % Triangle Wave
        function sig = triangle_wave_ref(app)
            sig = zeros(size(app.t));
            th = 1/(2*app.f);
            Tx = 0;
            for id = 1:length(app.t)
                if(app.t(id) < th)
                    sig(id) = -4*app.f*(app.t(id)-Tx) + 1;
                elseif(app.t(id) < (th+(1/(2*app.f))))
                    sig(id) = 4*app.f*(app.t(id)-Tx-(1/app.f)) + 1;
                else
                    th = th + 1/app.f;
                    Tx = Tx + 1/app.f;
                    sig(id) = -4*app.f*(app.t(id)-Tx) + 1;
                end
            end
        end
        
        % Saw-Tooth Wave
        function sig = saw_tooth_wave_ref(app)
            sig = zeros(size(app.t));
            th = 1/(2*app.f);
            Tx = 0;
            for id = 1:length(app.t)
                if(app.t(id) < th)
                    sig(id) = 2*app.f*(app.t(id)-Tx);
                elseif(app.t(id) < (th+(1/(2*app.f))))
                    sig(id) = 2*app.f*(app.t(id)-Tx-(1/app.f));
                else
                    th = th + 1/app.f;
                    Tx = Tx + 1/app.f;
                    sig(id) = 2*app.f*(app.t(id)-Tx);
                end
            end
        end
        
        % Plot Signal and Reference
        function fourier_plot(app)
            if(strcmp(app.mode,'Square'))
                app.ref = square_wave_ref(app);
                app.y = square_wave_fourier(app);
            elseif(strcmp(app.mode,'Triangle'))
                app.ref = triangle_wave_ref(app);
                app.y = triangle_wave_fourier(app);
            elseif(strcmp(app.mode, 'Saw-Tooth'))
                app.ref = saw_tooth_wave_ref(app);
                app.y = saw_tooth_wave_fourier(app);
            end
            plot(app.UIAxes, app.t, app.ref, 'b-', app.t, app.y, 'r-');
            legend(app.UIAxes, 'Reference', 'Signal');
            axis(app.UIAxes, 'tight');
            ylim(app.UIAxes, [-1.5 1.5]);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.N = app.NumberofwavesKnob.Value;
            app.t = [0:0.001:app.TimeKnob.Value];
            app.f = app.FrequencyKnob.Value;
            app.mode = app.SignalTypeListBox.Value;
            fourier_plot(app);
        end

        % Value changed function: TimeKnob
        function TimeKnobValueChanged(app, event)
            app.t = [0:0.001:app.TimeKnob.Value];
            fourier_plot(app);
        end

        % Value changed function: NumberofwavesKnob
        function NumberofwavesKnobValueChanged(app, event)
            app.N = app.NumberofwavesKnob.Value;
            fourier_plot(app);
        end

        % Value changed function: FrequencyKnob
        function FrequencyKnobValueChanged(app, event)
            app.f = app.FrequencyKnob.Value;
            fourier_plot(app);
        end

        % Value changed function: SignalTypeListBox
        function SignalTypeListBoxValueChanged(app, event)
            app.mode = app.SignalTypeListBox.Value;
            fourier_plot(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FourierSeriesVizualizerUIFigure = uifigure;
            app.FourierSeriesVizualizerUIFigure.Name = 'Fourier Series Vizualizer';
            setAutoResize(app, app.FourierSeriesVizualizerUIFigure, true)
            app.UIAxes = uiaxes(app.FourierSeriesVizualizerUIFigure);
            title(app.UIAxes, 'Signal');
            xlabel(app.UIAxes, 'Time [s]');
            app.FourierSeriesVizualizerLabel = uilabel(app.FourierSeriesVizualizerUIFigure);
            app.FourierSeriesVizualizerLabel.Text = 'Fourier Series Vizualizer';
            app.SignalTypeListBoxLabel = uilabel(app.FourierSeriesVizualizerUIFigure);
            app.SignalTypeListBoxLabel.Text = 'Signal Type';
            app.SignalTypeListBox = uilistbox(app.FourierSeriesVizualizerUIFigure);
            app.SignalTypeListBox.Items = {'Square', 'Triangle', 'Saw-Tooth'};
            app.SignalTypeListBox.ValueChangedFcn = createCallbackFcn(app, @SignalTypeListBoxValueChanged, true);
            app.SignalTypeListBox.Value = 'Square';
            app.TimeKnobLabel = uilabel(app.FourierSeriesVizualizerUIFigure);
            app.TimeKnobLabel.Text = 'Time';
            app.TimeKnob = uiknob(app.FourierSeriesVizualizerUIFigure, 'continuous');
            app.TimeKnob.Limits = [1 10];
            app.TimeKnob.ValueChangedFcn = createCallbackFcn(app, @TimeKnobValueChanged, true);
            app.TimeKnob.Value = 1;
            app.NumberofwavesKnobLabel = uilabel(app.FourierSeriesVizualizerUIFigure);
            app.NumberofwavesKnobLabel.Text = 'Number of waves';
            app.NumberofwavesKnob = uiknob(app.FourierSeriesVizualizerUIFigure, 'continuous');
            app.NumberofwavesKnob.ValueChangedFcn = createCallbackFcn(app, @NumberofwavesKnobValueChanged, true);
            app.FrequencyKnobLabel = uilabel(app.FourierSeriesVizualizerUIFigure);
            app.FrequencyKnobLabel.Text = 'Frequency';
            app.FrequencyKnob = uiknob(app.FourierSeriesVizualizerUIFigure, 'continuous');
            app.FrequencyKnob.Limits = [0 50];
            app.FrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @FrequencyKnobValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = FourierSeriesVisualizer()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FourierSeriesVizualizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FourierSeriesVizualizerUIFigure)
        end
    end
end